classdef runDiscoveryFTP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        PatientDBTab               matlab.ui.container.Tab
        UITable2                   matlab.ui.control.Table
        ActionsButtonGroup         matlab.ui.container.ButtonGroup
        PETReconButton             matlab.ui.control.ToggleButton
        DViewerButton_2            matlab.ui.control.ToggleButton
        DViewerButton              matlab.ui.control.ToggleButton
        HeaderButton               matlab.ui.control.ToggleButton
        ConsoleDBTab               matlab.ui.container.Tab
        HostIPLabel                matlab.ui.control.Label
        HostIPEditField            matlab.ui.control.EditField
        UsernameLabel              matlab.ui.control.Label
        UsernameEditField          matlab.ui.control.EditField
        PasswordLabel              matlab.ui.control.Label
        PasswordEditField          matlab.ui.control.EditField
        ConnectButton              matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        TransfertoLocalDBButton    matlab.ui.control.Button
        UITable3                   matlab.ui.control.Table
        Label                      matlab.ui.control.Label
        GEDiscoveryFTPClientLabel  matlab.ui.control.Label
        PoweredbyTheOttawaHospitalDivisionofNuclearMedicineLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        host % Description
        usr
        pass
        ftpobj
        consoleDB = '/usr/g/sdc_image_pool/images/'; % Dir where all the data is archived on console
        localDB = 'C:\Users\hjuma\Documents\MATLAB\Lesion Synthesis Toolbox\DiscoveryDR Client and Source/debug'; 
        debugmode = 0;
        indicies = 0; 
    end
    
    methods (Access = public)
        
        function updateFTPFileList(app)
            
            % Only on console
            
            if app.debugmode
                app.consoleDB = '/Volumes/HANIFHDD/Console Data/December 2018/hanif/';
                
                data = dir('/Volumes/HANIFHDD/Console Data/December 2018/hanif/');
                
                data = data(3:end);
            else
                cd(app.ftpobj,app.consoleDB)
                data = dir(app.ftpobj);
            end
            
            
            % Get all the studies (Folders)
            for i = 1:size(data,1)
                folders{i} = data(i).name;
            end
            
            %             for i = 1:length(folders)
            %                 fdata = dir([app.consoleDB '/' folders{i}]);
            %                 fdata = fdata(3:end); % get rid of dots doos
            %                 for l = 1:length(fdata)
            %                     files = listfiles('.img',[app.consoleDB '/' folders{i} '/' fdata(l).name]);
            %                     if ~isempty(files)
            %                         dicomhdr = dicominfo([app.consoleDB '/' folders{i} '/' fdata(l).name '/' files{1}]);
            %                         IDs{i} = dicomhdr.PatientID;
            %                         Date{i} = dicomhdr.AcquisitionDate;
            %                         if isfield(dicomhdr.PatientName,'GivenName') && isfield(dicomhdr.PatientName,'FamilyName')
            %                             Names{i} = [dicomhdr.PatientName.GivenName '  ' dicomhdr.PatientName.FamilyName];
            %                         end
            %
            %                     end
            %                 end
            %                 app.Label.Text = ['Populating Console DB ' num2str(i/length(folders)*100,3) '% Complete...'];
            %                 drawnow
            %             end
            
            for i = 1:length(folders) % num patients
                
                % Get into patient DIR
                cd(app.ftpobj,[app.consoleDB '/' folders{i}])
                
                patstudies = dir(app.ftpobj);
                dFolder{i} = folders{i};
                
                for k = 1:length(patstudies)
                    disp('Skipped - Suspected Non-Patient Study')
                    Names{i} = 'Multi-Scan-Study';
                    IDs{i} = '000000';
                    Date{i} = '00/00/00';
                    
                    
                    fname = patstudies(k).name;
                    cd(app.ftpobj,[app.consoleDB '/' folders{i} '/' fname])
                    sstudy = dir(app.ftpobj);
                    
                    for j = 1:length(sstudy)
                        cd(app.ftpobj,[app.consoleDB '/' folders{i} '/' fname,...
                            '/' sstudy(j).name]);
                        % Search for CT file (since its the smallest)
                        tempdata = dir(app.ftpobj);
                        if strfind(tempdata(1).name,'.CTDC')
                            disp('found CT')
                            temp = mget(app.ftpobj,tempdata(1).name);
                            dicomhdr = dicominfo(temp{1});
                            IDs{i} = dicomhdr.PatientID;
                            Date{i} = dicomhdr.AcquisitionDate;
                            if isfield(dicomhdr.PatientName,'GivenName') && isfield(dicomhdr.PatientName,'FamilyName')
                                Names{i} = [dicomhdr.PatientName.GivenName '  ' dicomhdr.PatientName.FamilyName];
                               
                                        if exist(temp{:})
						delete(temp{:})
					end
                            end
                            
                            break
                        
                        end
                    end
                    
                    break; 
                    
                end
            
            app.Label.Text = ['Populating Console DB ' num2str(i/length(folders)*100,3) '% Complete...'];
            drawnow
        end
        
        % Locate a single RPDC/CTAC file
        % Read DICOM Header
        % Populate FTP File List
        
        app.UITable.Data = [dFolder', IDs', Names', Date'];
        app.Label.Text = [' '];
        
    end
    
end


    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
        app.ConnectButton.BackgroundColor = [1 0 0];
        app.ConnectButton.Text = 'Connecting ... ';
        
        drawnow
        disp('Connecting')
        
        app.host = app.HostIPEditField.Value;
        app.usr = app.UsernameEditField.Value;
        app.pass = app.PasswordEditField.Value;
        
        if isempty(app.host) && isempty(app.usr) && isempty(app.pass)
            disp('Testing Mode')
            app.debugmode = 1;
        else
            
            try
                if isempty(app.usr) || isempty(app.pass)
                    app.ftpobj = ftp(app.host);
                else
                    app.ftpobj = ftp(app.host,app.usr,app.pass);
                end
            catch ME
                warndlg(ME.message,'Error Connecting to FTP Server')
            end
            
        end
        
        app.ConnectButton.BackgroundColor = [0.96 0.96 0.96];
        app.ConnectButton.Text = 'Connect';
        
        % Update FTP Server Display
        updateFTPFileList(app)

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)

        disp(['Patient - ' num2str(event.Indices(1))])
        app.indicies = event.Indices(1); 
        end

        % Button pushed function: TransfertoLocalDBButton
        function TransfertoLocalDBButtonPushed(app, event)
           disp('success')
           
           flder2get = app.UITable.Data(app.indicies,1);
           MRN = app.UITable.Data(app.indicies,2);
           flder2get = flder2get{:}; 
           
           cd(app.ftpobj,[app.consoleDB '/' flder2get]);
           
           inflder = dir(app.ftpobj);
           
           cd(app.ftpobj,[app.consoleDB '/' flder2get '/' inflder.name]);
           
           aflder = dir(app.ftpobj);
           
           j = 1;
           l = 1;
		
           for k = 1:length(aflder)
			cd(app.ftpobj,[app.consoleDB '/' flder2get '/' inflder.name '/' aflder(k).name]);
			files = dir(app.ftpobj);
			
			if ~isempty(strfind(files(1).name,'CTDC'))
				CTDC{j} = aflder(k).name;
				nCTDC(j) = length(files);
				j = j + 1;
			end
			
			if  ~isempty(strfind(files(1).name,'RPDC'))
				RPDC{l} = aflder(k).name;
				nRPDC(l) = length(files);
				l = l + 1;
			end
       	   end % End k = 1:length(aflder) 
           
           nbedpos = nRPDC - 3; % WellCounter/Norm/Geo3D
		
		if nbedpos == 8
			eslices = 299;
		elseif nbedpos == 7
			eslices = 263;
		else
			eslices = 0;
		end
		
		if eslices
			for k = 1:length(nCTDC)
				if nCTDC(k) == eslices
					CTDC = CTDC(k);
				end
			end
		end
		
           	MRN = MRN{:};
		mkdir(app.localDB,MRN);
  
  if length(CTDC) < 2
	
	CTDC = CTDC{:};
	
	mkdir([app.localDB filesep MRN],'CTAC');
	
	cd(app.ftpobj,[app.consoleDB '/' flder2get '/' inflder.name '/' CTDC]);
	files2get = app.ftpobj.dir;
	
	for k = 1:length(files2get)
		mget(app.ftpobj,files2get(k).name,[app.localDB filesep MRN filesep,'CTAC'])
	end
	
	mkdir([app.localDB filesep MRN],'raw');
	
	studydir = [app.consoleDB '/' flder2get '/' inflder.name '/' RPDC{:}];
	
	cd(app.ftpobj,studydir);
	files2get = app.ftpobj.dir;
	
	for k = 1:length(files2get)
		cd(app.ftpobj,studydir);
		
		mget(app.ftpobj,files2get(k).name,[app.localDB filesep MRN filesep,'raw']);
		
		tmp = [app.localDB filesep MRN filesep 'raw' filesep files2get(k).name];
		info = dicominfo(tmp);
		
		if isfield(info,'Private_0009_10e4')
			if strfind(info.Private_0009_10e4,'WB')
				sino = info.Private_0009_1062;
				[p f e] = fileparts(sino);
				cd(app.ftpobj,p);
				mget(app.ftpobj,f,[app.localDB filesep MRN filesep 'raw']);
			end
		elseif  isfield(info,'Private_0019_1007')
			if strfind(info.Private_0019_1007,'well counter')
				%disp('Well Counter Calibration File Found')
				%info.Private_0019_1007
			end
		elseif  isfield(info,'Private_0017_1005')
			if strfind(info.Private_0017_1005,'3D Geometric Calibration')
				geo3d = info.Private_0017_1007;
				[p f e] = fileparts(geo3d);
				cd(app.ftpobj,p);
				mget(app.ftpobj,f,[app.localDB filesep MRN]);
				movefile([app.localDB filesep MRN filesep f],[app.localDB filesep MRN filesep 'geo3d'])
				%disp('3D Geometric Calibration File Found')
			else strfind(info.Private_0017_1005,'PET 3D Normalization')
				norm3d = info.Private_0017_1007;
				[p f e] = fileparts(norm3d);
				cd(app.ftpobj,p);
				mget(app.ftpobj,f,[app.localDB filesep MRN]);
				movefile([app.localDB filesep MRN filesep f],[app.localDB filesep MRN filesep 'norm3d'])
			end
			
		end
		
	end
end 

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PatientDBTab = uitab(app.TabGroup);
            app.PatientDBTab.Title = 'Patient DB';
            app.UITable2 = uitable(app.PatientDBTab);
            app.UITable2.ColumnName = {'Patient ID'; 'Patient Name'; 'Date'; ''};
            app.UITable2.RowName = {};
            app.ActionsButtonGroup = uibuttongroup(app.PatientDBTab);
            app.ActionsButtonGroup.Title = 'Actions';
            app.PETReconButton = uitogglebutton(app.ActionsButtonGroup);
            app.PETReconButton.Text = 'PET Recon';
            app.PETReconButton.Value = true;
            app.DViewerButton_2 = uitogglebutton(app.ActionsButtonGroup);
            app.DViewerButton_2.Text = '3D Viewer';
            app.DViewerButton = uitogglebutton(app.ActionsButtonGroup);
            app.DViewerButton.Text = '2D Viewer';
            app.HeaderButton = uitogglebutton(app.ActionsButtonGroup);
            app.HeaderButton.Text = 'Header';
            app.ConsoleDBTab = uitab(app.TabGroup);
            app.ConsoleDBTab.Title = 'Console DB';
            app.HostIPLabel = uilabel(app.ConsoleDBTab);
            app.HostIPLabel.Text = 'Host IP:';
            app.HostIPEditField = uieditfield(app.ConsoleDBTab, 'text');
            app.UsernameLabel = uilabel(app.ConsoleDBTab);
            app.UsernameLabel.Text = 'Username:';
            app.UsernameEditField = uieditfield(app.ConsoleDBTab, 'text');
            app.PasswordLabel = uilabel(app.ConsoleDBTab);
            app.PasswordLabel.Text = 'Password:';
            app.PasswordEditField = uieditfield(app.ConsoleDBTab, 'text');
            app.ConnectButton = uibutton(app.ConsoleDBTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.UITable = uitable(app.ConsoleDBTab);
            app.UITable.ColumnName = {'Console ID'; 'Patient ID'; 'Patient Name'; 'Acqusition Date'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.TransfertoLocalDBButton = uibutton(app.ConsoleDBTab, 'push');
            app.TransfertoLocalDBButton.ButtonPushedFcn = createCallbackFcn(app, @TransfertoLocalDBButtonPushed, true);
            app.TransfertoLocalDBButton.Text = 'Transfer to Local DB';
            app.UITable3 = uitable(app.ConsoleDBTab);
            app.UITable3.ColumnName = {'Select'; 'Series Description'};
            app.UITable3.RowName = {};
            app.Label = uilabel(app.ConsoleDBTab);
            app.Label.Text = '';
            app.GEDiscoveryFTPClientLabel = uilabel(app.UIFigure);
            app.GEDiscoveryFTPClientLabel.Text = 'GE Discovery FTP Client';
            app.PoweredbyTheOttawaHospitalDivisionofNuclearMedicineLabel = uilabel(app.UIFigure);
            app.PoweredbyTheOttawaHospitalDivisionofNuclearMedicineLabel.Text = 'Powered by: The Ottawa Hospital Division of Nuclear Medicine ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = runDiscoveryFTP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end