classdef LesionPropertiesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LesionPropertiesUIFigure   matlab.ui.Figure
        PETModeTabGroup            matlab.ui.container.TabGroup
        AllcombinationsTab         matlab.ui.container.Tab
        ContrastxbackgroundLabel   matlab.ui.control.Label
        ContrastUITable            matlab.ui.control.Table
        SizemmLabel                matlab.ui.control.Label
        SizeUITable                matlab.ui.control.Table
        IndividualcombinationsTab  matlab.ui.container.Tab
        ContrastSizeUITable        matlab.ui.control.Table
        PETLabel                   matlab.ui.control.Label
        CTLabel                    matlab.ui.control.Label
        HULabel                    matlab.ui.control.Label
        IntensityEditFieldLabel    matlab.ui.control.Label
        CTIntensityEditField       matlab.ui.control.NumericEditField
        OKButton                   matlab.ui.control.Button
        SaveasNewPresetButton      matlab.ui.control.Button
        PresetsDropDownLabel       matlab.ui.control.Label
        PresetsDropDown            matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        output % Description
    end
    
    properties (Access = private)
        presets % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            path = fileparts(mfilename('fullpath'));
            filename = [path filesep 'Lesion property presets.mat'];
            if exist(filename,'file')
                load(filename,'Names','Data');
            else
                Names = {'Default Individual','Default Combination'};
                Data = {struct('Mode','Individual',...
                               'ContrastSize',[2, 20;4, 10],...
                               'CTIntensity',50),...
                        struct('Mode','Combination',...
                               'Contrast',[2;4],...
                               'Size',[10;20],...
                               'CTIntensity',50) };
            end
            app.PresetsDropDown.Items = Names;
            app.PresetsDropDown.ItemsData = Data;
            app.PresetsDropDown.Value = Data{1};
            app.PresetsDropDown.ValueChangedFcn;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            switch app.PETModeTabGroup.SelectedTab
                case app.AllcombinationsTab
                    contrast = app.ContrastUITable.Data;
                    radius = app.SizeUITable.Data;
                    [contrast, radius] = ndgrid(contrast,radius);
                    contrast = contrast(:);
                    radius = radius(:);
                case app.IndividualcombinationsTab
                    contrast = app.ContrastSizeUITable.Data(:,2);
                    radius = app.ContrastSizeUITable.Data(:,2);
            end
            app.output.PET.contrast = contrast;
            app.output.PET.radius = radius;
            app.output.CT.radius = unique(radius);
            app.output.CT.intensity = repmat(app.CTIntensityEditField.Value, [length(app.output.CT.radius), 1]);
        end

        % Close request function: LesionPropertiesUIFigure
        function LesionPropertiesUIFigureCloseRequest(app, event)
            app.output = [];
            delete(app)
        end

        % Value changed function: PresetsDropDown
        function PresetsDropDownValueChanged(app, event)
            value = app.PresetsDropDown.Value;
            switch value.Mode
                case 'Individual'
                    app.PETModeTabGroup.SelectedTab = app.IndividualcombinationsTab;
                    app.ContrastSizeUITable.Data = value.ContrastSize;
                case 'Combination'
                    app.PETModeTabGroup.SelectedTab = app.AllcombinationsTab;
                    app.ContrastUITable.Data = value.Contrast;
                    app.SizeUITable.Data = value.Size;
            end
            app.CTIntensityEditField.Value = value.CTIntensity;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LesionPropertiesUIFigure = uifigure('Visible', 'off');
            app.LesionPropertiesUIFigure.Name = 'Lesion properties';
            app.LesionPropertiesUIFigure.CloseRequestFcn = createCallbackFcn(app, @LesionPropertiesUIFigureCloseRequest, true);
            app.PETModeTabGroup = uitabgroup(app.LesionPropertiesUIFigure);
            app.AllcombinationsTab = uitab(app.PETModeTabGroup);
            app.AllcombinationsTab.Title = 'All combinations';
            app.ContrastxbackgroundLabel = uilabel(app.AllcombinationsTab);
            app.ContrastxbackgroundLabel.Text = 'Contrast (x background)';
            app.ContrastUITable = uitable(app.AllcombinationsTab);
            app.ContrastUITable.ColumnName = {'Contrast'};
            app.ContrastUITable.RowName = {};
            app.ContrastUITable.ColumnSortable = true;
            app.ContrastUITable.ColumnEditable = true;
            app.SizemmLabel = uilabel(app.AllcombinationsTab);
            app.SizemmLabel.Text = 'Size (mm)';
            app.SizeUITable = uitable(app.AllcombinationsTab);
            app.SizeUITable.ColumnName = {'Radius'};
            app.SizeUITable.RowName = {''};
            app.SizeUITable.ColumnSortable = true;
            app.SizeUITable.ColumnEditable = true;
            app.IndividualcombinationsTab = uitab(app.PETModeTabGroup);
            app.IndividualcombinationsTab.Title = 'Individual combinations';
            app.ContrastSizeUITable = uitable(app.IndividualcombinationsTab);
            app.ContrastSizeUITable.ColumnName = {'Contrast'; 'Radius'};
            app.ContrastSizeUITable.RowName = {};
            app.ContrastSizeUITable.ColumnSortable = true;
            app.ContrastSizeUITable.ColumnEditable = true;
            app.PETLabel = uilabel(app.LesionPropertiesUIFigure);
            app.PETLabel.Text = 'PET';
            app.CTLabel = uilabel(app.LesionPropertiesUIFigure);
            app.CTLabel.Text = 'CT';
            app.HULabel = uilabel(app.LesionPropertiesUIFigure);
            app.HULabel.Text = 'HU';
            app.IntensityEditFieldLabel = uilabel(app.LesionPropertiesUIFigure);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.CTIntensityEditField = uieditfield(app.LesionPropertiesUIFigure, 'numeric');
            app.OKButton = uibutton(app.LesionPropertiesUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SaveasNewPresetButton = uibutton(app.LesionPropertiesUIFigure, 'push');
            app.SaveasNewPresetButton.Text = 'Save as New Preset';
            app.PresetsDropDownLabel = uilabel(app.LesionPropertiesUIFigure);
            app.PresetsDropDownLabel.Text = 'Presets';
            app.PresetsDropDown = uidropdown(app.LesionPropertiesUIFigure);
            app.PresetsDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetsDropDownValueChanged, true);
            % Show the figure after all components are created
            app.LesionPropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LesionPropertiesGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LesionPropertiesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LesionPropertiesUIFigure)
        end
    end
end