classdef anonymizationUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnonymizationUIFigure      matlab.ui.Figure
        CancelButton               matlab.ui.control.Button
        OKButton                   matlab.ui.control.Button
        OriginalPatientIDLabel     matlab.ui.control.Label
        OriginalDateofBirthLabel   matlab.ui.control.Label
        OriginalGivenNameLabel     matlab.ui.control.Label
        OriginalFamilyNameLabel    matlab.ui.control.Label
        DateofBirthEditField       matlab.ui.control.EditField
        DateofBirthEditFieldLabel  matlab.ui.control.Label
        PatientIDEditField         matlab.ui.control.EditField
        PatientIDEditFieldLabel    matlab.ui.control.Label
        GivenNameEditField         matlab.ui.control.EditField
        GivenNameEditFieldLabel    matlab.ui.control.Label
        FamilyNameEditField        matlab.ui.control.EditField
        FamilyNameEditFieldLabel   matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

	
	properties (Access = public)
		UserData % Description
	end
	

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, originalData, newData)
			app.OriginalFamilyNameLabel.Text = originalData.FamilyName;
			app.OriginalGivenNameLabel.Text = originalData.GivenName;
			app.OriginalPatientIDLabel.Text = originalData.PatientID;
			app.OriginalDateofBirthLabel.Text = originalData.DateofBirth;
			
			app.FamilyNameEditField.Value = newData.FamilyName;
			app.GivenNameEditField.Value = newData.GivenName;
			app.PatientIDEditField.Value = newData.PatientID;
			app.DateofBirthEditField.Value = newData.DateofBirth;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
			app.UserData = struct('FamilyName', app.FamilyNameEditField.Value,...
				'GivenName', app.GivenNameEditField.Value,...
				'PatientID', app.PatientIDEditField.Value,...
				'DateofBirth', app.DateofBirthEditField.Value);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
			app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnonymizationUIFigure = uifigure('Visible', 'off');
            app.AnonymizationUIFigure.Name = 'Annonymization';
            app.AnonymizationUIFigure.Icon = 'Annonymize.png';
            app.Image = uiimage(app.AnonymizationUIFigure);
            app.Image.ImageSource = 'Annonymize.png';
            app.FamilyNameEditFieldLabel = uilabel(app.AnonymizationUIFigure);
            app.FamilyNameEditFieldLabel.Text = 'Family Name';
            app.FamilyNameEditField = uieditfield(app.AnonymizationUIFigure, 'text');
            app.GivenNameEditFieldLabel = uilabel(app.AnonymizationUIFigure);
            app.GivenNameEditFieldLabel.Text = 'Given Name';
            app.GivenNameEditField = uieditfield(app.AnonymizationUIFigure, 'text');
            app.PatientIDEditFieldLabel = uilabel(app.AnonymizationUIFigure);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.AnonymizationUIFigure, 'text');
            app.DateofBirthEditFieldLabel = uilabel(app.AnonymizationUIFigure);
            app.DateofBirthEditFieldLabel.Text = 'Date of Birth';
            app.DateofBirthEditField = uieditfield(app.AnonymizationUIFigure, 'text');
            app.OriginalFamilyNameLabel = uilabel(app.AnonymizationUIFigure);
            app.OriginalFamilyNameLabel.Text = 'Original Family Name';
            app.OriginalGivenNameLabel = uilabel(app.AnonymizationUIFigure);
            app.OriginalGivenNameLabel.Text = 'Original Given Name';
            app.OriginalDateofBirthLabel = uilabel(app.AnonymizationUIFigure);
            app.OriginalDateofBirthLabel.Text = 'Original Date of Birth';
            app.OriginalPatientIDLabel = uilabel(app.AnonymizationUIFigure);
            app.OriginalPatientIDLabel.Text = 'Original Patient ID';
            app.OKButton = uibutton(app.AnonymizationUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.AnonymizationUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.AnonymizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = anonymizationUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnonymizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnonymizationUIFigure)
        end
    end
end