classdef renameROI < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		RenameROIUIFigure        matlab.ui.Figure
		PresetNamesListBox       matlab.ui.control.ListBox
		PresetNamesListBoxLabel  matlab.ui.control.Label
		OldnameEditField         matlab.ui.control.EditField
		OldnameEditFieldLabel    matlab.ui.control.Label
		CancelButton             matlab.ui.control.Button
		AcceptButton             matlab.ui.control.Button
		NewnameEditField         matlab.ui.control.EditField
		NewnameEditFieldLabel    matlab.ui.control.Label
	end

	
	properties (Access = public)
		UserData = []; % Description
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app, oldName, nameList, title)
			app.OldnameEditField.Value = oldName;
			app.NewnameEditField.Value = oldName;
			app.PresetNamesListBox.Items = nameList;
			app.RenameROIUIFigure.Name = title;
		end

		% Button pushed function: CancelButton
		function CancelButtonPushed(app, event)
			app.delete;
		end

		% Button pushed function: AcceptButton
		function AcceptButtonPushed(app, event)
			app.UserData = app.NewnameEditField.Value;
		end

		% Clicked callback: PresetNamesListBox
		function PresetNamesListBoxClicked(app, event)
			item = event.InteractionInformation.Item;
			app.NewnameEditField.Value = app.PresetNamesListBox.Items{item};
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.RenameROIUIFigure = uifigure('Visible', 'off');
			app.RenameROIUIFigure.Name = 'Rename ROI';
			app.NewnameEditFieldLabel = uilabel(app.RenameROIUIFigure);
			app.NewnameEditFieldLabel.Text = 'New name';
			app.NewnameEditField = uieditfield(app.RenameROIUIFigure, 'text');
			app.AcceptButton = uibutton(app.RenameROIUIFigure, 'push');
			app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
			app.AcceptButton.Text = 'Accept';
			app.CancelButton = uibutton(app.RenameROIUIFigure, 'push');
			app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
			app.CancelButton.Text = 'Cancel';
			app.OldnameEditFieldLabel = uilabel(app.RenameROIUIFigure);
			app.OldnameEditFieldLabel.Text = 'Old name';
			app.OldnameEditField = uieditfield(app.RenameROIUIFigure, 'text');
			app.OldnameEditField.Editable = 'off';
			app.PresetNamesListBoxLabel = uilabel(app.RenameROIUIFigure);
			app.PresetNamesListBoxLabel.Text = 'Preset Names';
			app.PresetNamesListBox = uilistbox(app.RenameROIUIFigure);
			app.PresetNamesListBox.ClickedFcn = createCallbackFcn(app, @PresetNamesListBoxClicked, true);
			% Show the figure after all components are created
			app.RenameROIUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = renameROI(varargin)

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.RenameROIUIFigure)

				% Execute the startup function
				runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
			else

				% Focus the running singleton app
				figure(runningApp.RenameROIUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.RenameROIUIFigure)
		end
	end
end